classdef traf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        Panel        matlab.ui.container.Panel
        Lamp         matlab.ui.control.Lamp
        Lamp2        matlab.ui.control.Lamp
        Lamp3        matlab.ui.control.Lamp
        Slider       matlab.ui.control.Slider
        EditField    matlab.ui.control.EditField
        EditField2   matlab.ui.control.NumericEditField
        polisButton  matlab.ui.control.StateButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Lamp.Color = [0.5 0.5 0.5];
            app.Lamp2.Color = [0.5 0.5 0.5];
            app.Lamp3.Color = [0.5 0.5 0.5];
            
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            
            app.EditField2.Value = changingValue;
            
            polis_butonu = app.polisButton.Value;
            
            if (changingValue > 0 && changingValue < 30)
                 app.Lamp.Color = [1 0 0];
                app.Lamp2.Color = [0.5 0.5 0.5];
                app.Lamp3.Color = [0.5 0.5 0.5];
                app.EditField.Value = "DUR";
            
            
            elseif(changingValue > 30  && changingValue < 70) 
                app.Lamp.Color = [0.5 0.5 0.5];
                app.Lamp2.Color = [1 1 0];
                app.Lamp3.Color = [0.5 0.5 0.5];
                app.EditField.Value = "SABIR";
                
            elseif ((changingValue > 70  && changingValue < 100) && polis_butonu == 1 ) 
                
                app.Lamp.Color = [0.5 0.5 0.5];
                app.Lamp2.Color = [0.5 0.5 0.5];
                app.Lamp3.Color = [0 1 0];
                app.EditField.Value = "GEÇ";
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Lamp = uilamp(app.Panel);
            app.Lamp2 = uilamp(app.Panel);
            app.Lamp3 = uilamp(app.Panel);
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.polisButton = uibutton(app.UIFigure, 'state');
            app.polisButton.Text = 'polis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end